classdef part1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        UIAxes                          matlab.ui.control.UIAxes
        InputsLabel                     matlab.ui.control.Label
        OptionsLabel                    matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        Label_2                         matlab.ui.control.Label
        UniformCheckBox                 matlab.ui.control.CheckBox
        NonuniformCheckBox              matlab.ui.control.CheckBox
        MidriseCheckBox                 matlab.ui.control.CheckBox
        MidtreadCheckBox                matlab.ui.control.CheckBox
        tEditFieldLabel                 matlab.ui.control.Label
        tEditField                      matlab.ui.control.EditField
        mEditFieldLabel                 matlab.ui.control.Label
        mEditField                      matlab.ui.control.EditField
        MeansquarequantizationerrorLabel  matlab.ui.control.Label
        mseOutputLabel                  matlab.ui.control.Label
        QUANTIZEButton                  matlab.ui.control.Button
        NumberofLevelsEditFieldLabel    matlab.ui.control.Label
        NumberofLevelsEditField_U       matlab.ui.control.NumericEditField
        PeakquantizationlevelEditField_2Label  matlab.ui.control.Label
        PeakquantizationlevelEditField_U  matlab.ui.control.NumericEditField
        NumberofLevelsEditField_2Label  matlab.ui.control.Label
        NumberofLevelsEditField_nonU    matlab.ui.control.NumericEditField
        PeakquantizationlevelEditField_3Label  matlab.ui.control.Label
        PeakquantizationlevelEditField_nonU  matlab.ui.control.NumericEditField
        MuEditField_2Label              matlab.ui.control.Label
        MuEditField                     matlab.ui.control.NumericEditField
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: QUANTIZEButton
        function QUANTIZEButtonPushed(app, event)
            % Read m 
            str = app.mEditField.Value;
            str=erase(str,{'[',']'}); 
            str=split(str,{' ',','});
            m = str2double(str)'; 
            % Read t
            str = app.tEditField.Value;
            str=erase(str,{'[',']'}); 
            str=split(str,{' ',','});
            t = str2double(str)'; 
            
            assignin('base','m',m);
            assignin('base','t',t);
            
            y = zeros(1, length(m));
            
            % If uniform quantization is selected
            if app.UniformCheckBox.Value
               l = app.NumberofLevelsEditField_U.Value; 
               mp = app.PeakquantizationlevelEditField_U.Value;
               step = (2*mp)/l;
               
               if app.MidriseCheckBox.Value
                   p_position = 0 + step/2; 
                   n_position = 0 - step/2; 
                   steps = p_position;
                   if l>1
                       steps = [steps,n_position];
                       k = l-2;
                   end                
               else
                   steps = 0;
                   p_position = 0; 
                   n_position = 0;
                   k = l -1;
               end
                
               for i=1:ceil(k/2)
                   p_position = p_position + step; 
                   steps = [steps, p_position];
               end
               for i=1:floor(k/2)
                   n_position = n_position - step;
                   steps = [steps, n_position];
               end
               steps = sort(steps);
               for i = 1:length(m)
                   if m(i) > steps(l)
                      y(i) = steps(l);
                   end
                   for j = 1:l
                      if m(i) < steps(j) || (m(i) >= steps(j) && m(i) < steps(j) + step/2)
                          y(i) = steps(j); 
                          break
                      end
                   end
               end
            end
            
            % If nonuniform quantization is selected
            if app.NonuniformCheckBox.Value
                l = app.NumberofLevelsEditField_nonU.Value; 
                mp = app.PeakquantizationlevelEditField_nonU.Value; 
                mu = app.MuEditField.Value;
                if mu == 0
                    m_mued = m/mp; 
                else 
                    m_mued = sign(m).*log(1+ mu.*abs(m/mp))./log(1+mu);
                end                
                assignin('base','m_mued',m_mued);
                step = 2/l; 
                steps = 0;
                p_position = 0; 
                n_position = 0;
                k = l - 1;
                for i=1:ceil(k/2)
                    p_position = p_position + step; 
                    steps = [steps, p_position];
                end
                for i=1:floor(k/2)
                    n_position = n_position - step;
                    steps = [steps, n_position];
                end
                steps = sort(steps);                
                for i = 1:length(m)
                   if m_mued(i) > steps(l)
                      y(i) = steps(l);
                   end
                   for j = 1:l
                      if m_mued(i) < steps(j) || (m_mued(i) >= steps(j) && m_mued(i) < steps(j) + step/2)
                          y(i) = steps(j); 
                          break
                      end
                   end
                end
                if mu == 0
                    y = y.*mp; 
                else
                    y = sign(y).*(mp/mu).*((mu + 1).^(abs(y)) - 1); 
                end
                
            end
            mse = immse(m, y); 
            assignin('base','steps',steps);
            assignin('base','y',y);
            assignin('base','mse',mse);
            
            grid(app.UIAxes, "on") 
            plot(app.UIAxes, t, m);
            hold(app.UIAxes,'on')
            stairs(app.UIAxes, t, y);
            hold(app.UIAxes,'off')
            legend(app.UIAxes,"Input Signal", "Quantized Signal");
            app.mseOutputLabel.Text = string(mse); 
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Input and Quantized Signals')
            xlabel(app.UIAxes, 'time (sec)')
            ylabel(app.UIAxes, 'Voltage (V)')
            app.InputsLabel = uilabel(app.UIFigure);
            app.InputsLabel.Text = 'Inputs: ';
            app.OptionsLabel = uilabel(app.UIFigure);
            app.OptionsLabel.Text = 'Options:';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '';
            app.UniformCheckBox = uicheckbox(app.UIFigure);
            app.UniformCheckBox.Text = 'Uniform';
            app.NonuniformCheckBox = uicheckbox(app.UIFigure);
            app.NonuniformCheckBox.Text = 'Nonuniform';
            app.MidriseCheckBox = uicheckbox(app.UIFigure);
            app.MidriseCheckBox.Text = 'Midrise';
            app.MidtreadCheckBox = uicheckbox(app.UIFigure);
            app.MidtreadCheckBox.Text = 'Midtread';
            app.tEditFieldLabel = uilabel(app.UIFigure);
            app.tEditFieldLabel.Text = 't:';
            app.tEditField = uieditfield(app.UIFigure, 'text');
            app.mEditFieldLabel = uilabel(app.UIFigure);
            app.mEditFieldLabel.Text = 'm:';
            app.mEditField = uieditfield(app.UIFigure, 'text');
            app.MeansquarequantizationerrorLabel = uilabel(app.UIFigure);
            app.MeansquarequantizationerrorLabel.Text = 'Mean square quantization error = ';
            app.mseOutputLabel = uilabel(app.UIFigure);
            app.mseOutputLabel.Text = 'mse Output';
            app.QUANTIZEButton = uibutton(app.UIFigure, 'push');
            app.QUANTIZEButton.ButtonPushedFcn = createCallbackFcn(app, @QUANTIZEButtonPushed, true);
            app.QUANTIZEButton.Text = 'QUANTIZE!';
            app.NumberofLevelsEditFieldLabel = uilabel(app.UIFigure);
            app.NumberofLevelsEditFieldLabel.Text = 'Number of Levels:';
            app.NumberofLevelsEditField_U = uieditfield(app.UIFigure, 'numeric');
            app.PeakquantizationlevelEditField_2Label = uilabel(app.UIFigure);
            app.PeakquantizationlevelEditField_2Label.Text = 'Peak quantization level:';
            app.PeakquantizationlevelEditField_U = uieditfield(app.UIFigure, 'numeric');
            app.NumberofLevelsEditField_2Label = uilabel(app.UIFigure);
            app.NumberofLevelsEditField_2Label.Text = 'Number of Levels:';
            app.NumberofLevelsEditField_nonU = uieditfield(app.UIFigure, 'numeric');
            app.PeakquantizationlevelEditField_3Label = uilabel(app.UIFigure);
            app.PeakquantizationlevelEditField_3Label.Text = 'Peak quantization level:';
            app.PeakquantizationlevelEditField_nonU = uieditfield(app.UIFigure, 'numeric');
            app.MuEditField_2Label = uilabel(app.UIFigure);
            app.MuEditField_2Label.Text = 'Mu:';
            app.MuEditField = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = part1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end