classdef part2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SignalEncoderUIFigure      matlab.ui.Figure
        UIAxes                     matlab.ui.control.UIAxes
        BitTimeEditFieldLabel      matlab.ui.control.Label
        BitTimeEditField           matlab.ui.control.NumericEditField
        AmplitudeEditFieldLabel    matlab.ui.control.Label
        AmplitudeEditField         matlab.ui.control.NumericEditField
        ENCODEButton               matlab.ui.control.Button
        EncodingDropDownLabel      matlab.ui.control.Label
        EncodingDropDown           matlab.ui.control.DropDown
        BitStreamEditFieldLabel    matlab.ui.control.Label
        BitStreamEditField         matlab.ui.control.EditField
        NEditFieldLabel            matlab.ui.control.Label
        NEditField                 matlab.ui.control.NumericEditField
        GenerateNRandomBitsButton  matlab.ui.control.Button
    end

    
    properties (Access = private)
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ENCODEButton
        function ENCODEButtonPushed(app, event)
            
            % Extract the bit stream
            str = app.BitStreamEditField.Value;
            str = erase(str,{'[',']'}); 
            str = split(str,{' ',','});
            bitStream = rmmissing(str2double(str))';
            assignin("base", "bitStream", bitStream);
            
            % Extract the bit time duration
            Tb = app.BitTimeEditField.Value;
            assignin("base", "Tb", Tb);

            % Extract the bit amplitude
            A = app.AmplitudeEditField.Value;
            assignin("base", "A", A);
            
            % Extract the bit encoding
            encoding = app.EncodingDropDown.Value;
            
            % Encode using the specified encoding
            encoded = encoder(bitStream, Tb, A, encoding);
            assignin("base", "encoded", encoded);
            
            % Plot the encoded bit stream
            t = 0:Tb*length(bitStream)-1;
            stairs(app.UIAxes, t, encoded);
            
        end

        % Button pushed function: GenerateNRandomBitsButton
        function GenerateNRandomBitsButtonPushed(app, event)
            N = app.NEditField.Value;
            randomBits = randi([0 1], 1, N);
            app.BitStreamEditField.Value = num2str(randomBits);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SignalEncoderUIFigure = uifigure('Visible', 'off');
            app.SignalEncoderUIFigure.Name = 'Signal Encoder';
            app.UIAxes = uiaxes(app.SignalEncoderUIFigure);
            title(app.UIAxes, 'Encoded Bit Stream')
            xlabel(app.UIAxes, 'Time')
            ylabel(app.UIAxes, 'Voltage (V)')
            app.BitTimeEditFieldLabel = uilabel(app.SignalEncoderUIFigure);
            app.BitTimeEditFieldLabel.Text = 'Bit  Time';
            app.BitTimeEditField = uieditfield(app.SignalEncoderUIFigure, 'numeric');
            app.AmplitudeEditFieldLabel = uilabel(app.SignalEncoderUIFigure);
            app.AmplitudeEditFieldLabel.Text = 'Amplitude';
            app.AmplitudeEditField = uieditfield(app.SignalEncoderUIFigure, 'numeric');
            app.ENCODEButton = uibutton(app.SignalEncoderUIFigure, 'push');
            app.ENCODEButton.ButtonPushedFcn = createCallbackFcn(app, @ENCODEButtonPushed, true);
            app.ENCODEButton.Text = 'ENCODE!';
            app.EncodingDropDownLabel = uilabel(app.SignalEncoderUIFigure);
            app.EncodingDropDownLabel.Text = 'Encoding';
            app.EncodingDropDown = uidropdown(app.SignalEncoderUIFigure);
            app.EncodingDropDown.Items = {'Unipolar NRZ', 'Polar RZ', 'Alternate Mark Inversion', 'Manchester'};
            app.EncodingDropDown.Value = 'Unipolar NRZ';
            app.BitStreamEditFieldLabel = uilabel(app.SignalEncoderUIFigure);
            app.BitStreamEditFieldLabel.Text = 'Bit Stream';
            app.BitStreamEditField = uieditfield(app.SignalEncoderUIFigure, 'text');
            app.GenerateNRandomBitsButton = uibutton(app.SignalEncoderUIFigure, 'push');
            app.GenerateNRandomBitsButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateNRandomBitsButtonPushed, true);
            app.GenerateNRandomBitsButton.Text = 'Generate N Random Bits';
            app.NEditFieldLabel = uilabel(app.SignalEncoderUIFigure);
            app.NEditFieldLabel.Text = 'N';
            app.NEditField = uieditfield(app.SignalEncoderUIFigure, 'numeric');
            % Show the figure after all components are created
            app.SignalEncoderUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = part2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SignalEncoderUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SignalEncoderUIFigure)
        end
    end
end